classdef wifi_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DisconnectButton        matlab.ui.control.Button
        ConnectButton           matlab.ui.control.Button
        basedutycycleEditField  matlab.ui.control.NumericEditField
        basedutycycleLabel      matlab.ui.control.Label
        kdEditFieldLabel        matlab.ui.control.Label
        kdEditField             matlab.ui.control.NumericEditField
        kpEditField             matlab.ui.control.NumericEditField
        kpEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        connected
        tcp % tcp connection
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: basedutycycleEditField
        function basedutycycleEditFieldValueChanged(app, event)
            value = app.basedutycycleEditField.Value;
            write(app.tcp, "d" + value + newline);
            
        end

        % Value changed function: kpEditField
        function kpEditFieldValueChanged(app, event)
            value = app.kpEditField.Value;
            write(app.tcp, "P" + value + newline);
            
        end

        % Value changed function: kdEditField
        function kdEditFieldValueChanged(app, event)
            value = app.kdEditField.Value;
            write(app.tcp, "D" + value + newline);
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connected = false;
            espIP = '192.168.4.1'; % default softAPIP address
            espPort = 80; % this port must match the esp server port
            
            while (~app.connected)
                try
                    app.tcp = tcpclient(espIP, espPort, 'Timeout', 5);
                catch ME
                    disp("tcp server not running, could not connect");
                end
                write(app.tcp, uint8('X'), "uint8"); % ping server
                app.connected = true;
                disp("Connected!");
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % …after you’re finished sending/receiving…
            clear app.tcp      % this deletes the tcpclient object and closes the socket
            app.connected = false;
            disp("Connection closed.");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.kpEditFieldLabel = uilabel(app.UIFigure);
            app.kpEditFieldLabel.Text = 'kp';
            app.kpEditField = uieditfield(app.UIFigure, 'numeric');
            app.kpEditField.ValueChangedFcn = createCallbackFcn(app, @kpEditFieldValueChanged, true);
            app.kdEditField = uieditfield(app.UIFigure, 'numeric');
            app.kdEditField.ValueChangedFcn = createCallbackFcn(app, @kdEditFieldValueChanged, true);
            app.kdEditFieldLabel = uilabel(app.UIFigure);
            app.kdEditFieldLabel.Text = 'kd';
            app.basedutycycleLabel = uilabel(app.UIFigure);
            app.basedutycycleLabel.Text = {'base duty '; 'cycle'};
            app.basedutycycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.basedutycycleEditField.ValueChangedFcn = createCallbackFcn(app, @basedutycycleEditFieldValueChanged, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wifi_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end