classdef wifi_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ClearButton             matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        TextAreaLabel           matlab.ui.control.Label
        StartButton             matlab.ui.control.Button
        DisconnectButton        matlab.ui.control.Button
        ConnectButton           matlab.ui.control.Button
        basedutycycleEditField  matlab.ui.control.NumericEditField
        basedutycycleLabel      matlab.ui.control.Label
        kdEditFieldLabel        matlab.ui.control.Label
        kdEditField             matlab.ui.control.NumericEditField
        kpEditField             matlab.ui.control.NumericEditField
        kpEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        connected
        tcp % tcp connection
        file
    end
    
    methods (Access = private)
        
        function display_text(app,text)
            oldText = app.TextArea.Value;
            app.TextArea.Value = [oldText;text];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.connected = false;
            app.TextArea.Value = "";
            app.file = fopen( 'history.txt', 'a' );
            
        end

        % Value changed function: basedutycycleEditField
        function basedutycycleEditFieldValueChanged(app, event)
            if (~app.connected)
                disp("Server not connected")
                display_text(app,"Server not connected");
                app.basedutycycleEditField.Value = 0;
            else
                value = app.basedutycycleEditField.Value;
                write(app.tcp, "B" + value + newline);
                %display_text(app,"Sent: " + "B" + value + newline);

                pause(1);
                echo = readline(app.tcp);
                disp("Sent: ")
                disp(echo);
                display_text(app,"ESP Received: " + echo);
            end
            
        end

        % Value changed function: kpEditField
        function kpEditFieldValueChanged(app, event)
            if (~app.connected)
                disp("Server not connected")
                display_text(app,"Server not connected");
                app.kpEditField.Value = 0;
            else
                value = app.kpEditField.Value;
                write(app.tcp, "P" + value + newline);
                % wait for esp to echo
                pause(1);
                echo = readline(app.tcp);
                disp("Sent: ")
                disp(echo);
                display_text(app,"ESP Received: " + echo);
            end
            
        end

        % Value changed function: kdEditField
        function kdEditFieldValueChanged(app, event)
            if (~app.connected)
                disp("Server not connected")
                display_text(app,"Server not connected");
                app.kdEditField.Value = 0;
            else
                value = app.kdEditField.Value;
                write(app.tcp, "D" + value + newline);
                % wait for esp to echo
                pause(1);
                echo = readline(app.tcp);
                disp("Sent: ")
                disp(echo);
                display_text(app,"ESP Received: " + echo);

            end
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connected = false;
            espIP = '192.168.4.1'; % default softAPIP address
            espPort = 80; % this port must match the esp server port
            %tic; % record starting time
            % change to a time basis
            while (~app.connected) % only try for max 10 seconds
                try
                    app.tcp = tcpclient(espIP, espPort, 'Timeout', 5);
                    %write(app.tcp, uint8('X'), "uint8"); % ping server
                    app.connected = true;
                    disp("Connected!");
                    display_text(app,"Connected!");
                catch ME
                    disp("tcp server not running, could not connect");
                    display_text(app,"tcp server not running, could not connect");
                    pause(0.5);
                end
                
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % …after you’re finished sending/receiving…
            clear app.tcp      % this deletes the tcpclient object and closes the socket
            app.connected = false;
            disp("Connection closed.");
            display_text(app,"Connection closed.");
            app.basedutycycleEditField.Value = 0;
            app.kpEditField.Value = 0;
            app.kdEditField.Value = 0;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if (~app.connected)
                disp("Server not connected")
                display_text(app,"Server not connected");
            else
                % send an 's' to start
                write(app.tcp, "s" + newline);
                display_text(app,"Started! Connection is now closed");

                % close connection
                clear app.tcp      % deletes the tcpclient object and closes the socket
                app.connected = false;
                disp("Connection closed.");
                display_text(app,"Connection closed.");

                fprintf(app.file,"Kp: %d Kd: %d\n", app.kpEditField.Value, app.kdEditField.Value);

                % reset num boxes
                app.basedutycycleEditField.Value = 0;
                app.kpEditField.Value = 0;
                app.kdEditField.Value = 0;
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.TextArea.Value = "";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.file);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.kpEditFieldLabel = uilabel(app.UIFigure);
            app.kpEditFieldLabel.Text = 'kp';
            app.kpEditField = uieditfield(app.UIFigure, 'numeric');
            app.kpEditField.ValueChangedFcn = createCallbackFcn(app, @kpEditFieldValueChanged, true);
            app.kdEditField = uieditfield(app.UIFigure, 'numeric');
            app.kdEditField.ValueChangedFcn = createCallbackFcn(app, @kdEditFieldValueChanged, true);
            app.kdEditFieldLabel = uilabel(app.UIFigure);
            app.kdEditFieldLabel.Text = 'kd';
            app.basedutycycleLabel = uilabel(app.UIFigure);
            app.basedutycycleLabel.Text = {'base duty '; 'cycle'};
            app.basedutycycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.basedutycycleEditField.ValueChangedFcn = createCallbackFcn(app, @basedutycycleEditFieldValueChanged, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wifi_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end